classdef MammographySimulatorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ResetButton                    matlab.ui.control.Button
        PhantomthicknessDropDown       matlab.ui.control.DropDown
        PhantomthicknessDropDownLabel  matlab.ui.control.Label
        DistancebetweenPhantomandFilmObjecttoImageOIDDropDown  matlab.ui.control.DropDown
        DistancebetweenPhantomandFilmObjecttoImageOIDDropDownLabel  matlab.ui.control.Label
        Generate2DImagefor3DPhantomButton  matlab.ui.control.Button
        Generate1DProfileFrom2DphantomButton  matlab.ui.control.Button
        AngleindegreesDropDown         matlab.ui.control.DropDown
        AngleindegreesLabel            matlab.ui.control.Label
        DistancebetweenXRaySourceandPhantomSourcetoImageSIDDropDown  matlab.ui.control.DropDown
        DistancebetweenXRaySourceandPhantomLabel  matlab.ui.control.Label
        EnergyDropDown                 matlab.ui.control.DropDown
        EnergyDropDownLabel            matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        size2D = 256;  % Size for the 2D phantom
        size3D = 128;  % Size for the 3D phantom
        muValues = containers.Map({'50', '75', '100'}, [0.03, 0.06, 0.2]);
    end
    
    methods (Access = private)
        
        function phantom = generatePhantom(app)
              % Generate a 2D phantom with two structures: outer tissue and inner lesion
            [X, Y] = meshgrid(linspace(-1, 1, app.size2D), linspace(-1, 1, app.size2D));
            outerTissue = sqrt(X.^2 + Y.^2) <= 0.5; % Outer circular mask (tissue)
            innerLesion = sqrt(X.^2 + Y.^2) <= 0.2; % Inner smaller circle (lesion)
            
            % Assign different labels for structures: 1 for tissue, 2 for lesion
            phantom = zeros(app.size2D);
            phantom(outerTissue) = 1;   % Outer tissue
            phantom(innerLesion) = 2;   % Lesion
        end

        function image2D = simulateXRay(app, phantom, energy, SID, OID, angle, phantomThickness)
           % Get the compression factor based on the dropdown selection
            compressionIndex = find(strcmp(app.CompressionLevels, app.BreastCompressionDropDown.Value));
            compressionFactor = app.CompressionFactors(compressionIndex);
            
            % Adjust phantom thickness by the compression factor
            adjustedThickness = phantomThickness * compressionFactor;
            
            % Adjust µ values for different structures based on energy levels
            muValues = containers.Map({'50', '75', '100'}, {[0.02, 0.08], [0.04, 0.1], [0.06, 0.2]});
            mu = muValues(num2str(energy));
            
            % Rotate the phantom based on the specified angle
            rotatedPhantom = imrotate(phantom, angle, 'bilinear', 'crop');
            
            % Tissue attenuation depends on the adjusted phantom thickness
            tissueAttenuation = exp(-mu(1) * adjustedThickness);
            
            % Lesion attenuation is constant (not dependent on thickness)
            lesionAttenuation = exp(-mu(2));
            
            % Simulate X-ray attenuation for different structures
            image2D = zeros(size(rotatedPhantom));
            image2D(rotatedPhantom == 1) = tissueAttenuation;
            image2D(rotatedPhantom == 2) = lesionAttenuation;
            
            % Apply inverse square law for intensity decay
            intensityDecay = 1 / ((SID + OID)^2);
            image2D = image2D * intensityDecay;
        end

        function phantom3D = generate3DPhantom(app, phantomThickness)
              % Define the ellipsoids for the custom 3D phantom
            % Format: [A, a, b, c, x0, y0, z0, phi, theta, psi]
            ellipsoids = [
                % Outer spherical structure representing the breast tissue
                0.05, 0.8, 0.8, 0.8, 0, 0, 0, 0, 0, 0;   % Lower density tissue
                % Central lesion (higher density, smaller size)
                0.5, 0.15, 0.15, 0.15, 0, 0, 0, 0, 0, 0    % Higher density lesion
            ];
        
            % Generate the custom 3D phantom
            phantom3D = phantom3d(ellipsoids, app.size3D);
        
            % Scale the phantom in the Z-direction based on the phantomThickness
            [dimX, dimY, dimZ] = size(phantom3D);
            phantom3D = imresize3(phantom3D, [dimX, dimY, round(dimZ * phantomThickness)]);
            
        end

        function decayFactor = computeIntensityDecay(app, sourceToPhantomDistance, phantomToDetectorDistance)
           % Calculate decay for the source-to-phantom distance
            decaySourceToPhantom = 1 / (sourceToPhantomDistance^2);
            fprintf("Decay Source to Phantom: %e\n", decaySourceToPhantom);
            
            % Calculate decay for the phantom-to-detector distance
            decayPhantomToDetector = 1 / (phantomToDetectorDistance^2);
            fprintf("Decay Phantom to Detector: %e\n", decayPhantomToDetector);
            
            % Combine the decay factors
            decayFactor = decaySourceToPhantom * decayPhantomToDetector;
            fprintf("Combined Decay Factor: %e\n", decayFactor);
            
            % Adjust the clamping range to allow for more variation
            minDecay = 1e-6; % Reduced the minimum clamp value
            maxDecay = 1e-2; % Increased the maximum clamp value
            decayFactor = max(minDecay, min(maxDecay, decayFactor)); % Corrected clamping
            fprintf("Intensity Decay Factor (after clamping): %e\n", decayFactor);
          end

        function projection = project_phantom(app,phantom, SID, OID)
            [dimX, dimY, dimZ] = size(phantom);
            %magnification = SID / (SID - OID);
            magnification = (SID + OID) / SID;
            
            % Create an empty projection image scaled by magnification
            projection = zeros(round(dimX * magnification), round(dimY * magnification));
            
            % Sum along the Z-axis (depth)
            for z = 1:dimZ
                % Accumulate slices into the projection
                slice = imresize(phantom(:, :, z), magnification);
                projection = projection + slice;
            end
        end
       function image2D = simulateXRayFrom3D(app, phantom3D, energy, SID, OID, angle, rotationAxis, phantomThickness, resetSimulation)
            % If resetSimulation is true, reinitialize temporary variables
            if resetSimulation
                image2D = zeros(size(phantom3D, 1), size(phantom3D, 2)); % Reset image to zeros
            end
            
            % Adjust µ values based on energy levels
            mu = app.muValues(num2str(energy));
            
            % Scale the phantom based on phantomThickness before projecting
            phantom3D = generate3DPhantom(app, phantomThickness); % Apply scaling here
            
            % Rotate the 3D phantom using the specified angle and axis
            rotatedPhantom3D = imrotate3(phantom3D, angle, rotationAxis, 'linear', 'loose');
            
            % Ensure the size of rotatedPhantom3D matches the original phantom size for projection
            [dimX, dimY, dimZ] = size(phantom3D);
            rotatedPhantom3D = imresize3(rotatedPhantom3D, [dimX, dimY, dimZ]);
            
            % Project the rotated phantom along the Z-axis (integrating attenuation)
            attenuation = sum(rotatedPhantom3D, 3);
            
            % Apply exponential attenuation to simulate X-ray absorption for primary radiation
            primaryRadiation = exp(-mu * attenuation * phantomThickness);
            
            % Apply inverse square law for primary radiation intensity decay
            primaryDecayFactor = 1 / (SID^2);
            primaryRadiation = primaryRadiation * primaryDecayFactor;
            
            % Calculate magnification, ensuring it's within a reasonable range
            magnification = SID / max(1, (SID - OID));  % Prevent division by a small number
            if magnification > 3  % Cap excessive magnification
                magnification = 3;
            end
            
            % Resize the projection based on magnification
            [height, width] = size(primaryRadiation);
            primaryRadiation = imresize(primaryRadiation, magnification);
            
            % Model the scattered radiation
            scatterDecayFactor = 1 / ((SID + OID)^2);
            scatterRadiation = exp(-mu * attenuation * phantomThickness) * scatterDecayFactor;
            scatterRadiation = imresize(scatterRadiation, size(primaryRadiation));
            
            % Ensure sizes match before adding
            assert(isequal(size(primaryRadiation), size(scatterRadiation)), ...
                'Error: Arrays have incompatible sizes for addition');
            
            % Combine primary and scattered radiation
            image2D = primaryRadiation + scatterRadiation;
            
            % Normalize and enhance the image for better visibility
            image2D = 1 - image2D;  % Invert for better contrast
            gamma = 0.6;  % Adjust gamma for contrast enhancement
            image2D = image2D.^gamma;
            
            % Normalize the image to range [0, 1]
            image2D = (image2D - min(image2D(:))) / (max(image2D(:)) - min(image2D(:)));
            
            % Optional: Sharpen if magnification caused blurring
            if magnification > 1.5
                image2D = imsharpen(image2D);
            end
       end
         
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Generate1DProfileFrom2DphantomButton
        function Generate1DProfileFrom2DphantomButtonPushed(app, event)
            energy = str2double(app.EnergyDropDown.Value);
            angle = str2double(app.AngleindegreesDropDown.Value);
            SID = str2double(app.DistancebetweenXRaySourceandPhantomSourcetoImageSIDDropDown.Value);
            OID = str2double(app.DistancebetweenPhantomandFilmObjecttoImageOIDDropDown.Value);
            phantomThickness = str2double(app.PhantomthicknessDropDown.Value);
            
            % Generate phantom
            phantom = app.generatePhantom();
            
            % Simulate X-ray image with the new parameters
            image2D = app.simulateXRay(phantom, energy, SID, OID, angle, phantomThickness);
            
            % Generate 1D profile
            profile1D = sum(image2D, 1); % Summing along rows for profile
            
            % Plot profile
            plot(app.UIAxes, profile1D);
            title(app.UIAxes, '1D Profile');
        end

        % Button pushed function: Generate2DImagefor3DPhantomButton
        function Generate2DImagefor3DPhantomButtonPushed(app, event)
           % Get user inputs for energy, distance, and angle
            energy = str2double(app.EnergyDropDown.Value);
            SID = str2double(app.DistancebetweenXRaySourceandPhantomSourcetoImageSIDDropDown.Value);
            OID = str2double(app.DistancebetweenPhantomandFilmObjecttoImageOIDDropDown.Value);
            phantomThickness = str2double(app.PhantomthicknessDropDown.Value);
            angle = str2double(app.AngleindegreesDropDown.Value);
              
            % Choose the rotation axis (defaulting to X-axis)
            rotationAxis = [1, 0, 0];
        
            % Generate the 3D phantom
            phantom3D = app.generate3DPhantom(phantomThickness);
        
            % Set resetSimulation flag (for example, defaulting to false)
            resetSimulation = false;
        
            % Generate the 2D X-ray projection
            image2D = app.simulateXRayFrom3D(phantom3D, energy, SID, OID, angle, rotationAxis, phantomThickness, resetSimulation);
        
            % Display the generated 2D image on the axes
            imagesc(app.UIAxes, image2D);
            colormap(app.UIAxes, 'gray');
            title(app.UIAxes, '2D X-ray Image of 3D Phantom');
            colorbar(app.UIAxes);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes);
            app.PhantomthicknessDropDown.Value = '2';
            app.DistancebetweenXRaySourceandPhantomSourcetoImageSIDDropDown.Value = '30';
            app.DistancebetweenPhantomandFilmObjecttoImageOIDDropDown.Value = '0.1';
            app.EnergyDropDown.Value = '50';
            app.AngleindegreesDropDown.Value = '15';
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.EnergyDropDownLabel = uilabel(app.UIFigure);
            app.EnergyDropDownLabel.Text = 'Energy';
            app.EnergyDropDown = uidropdown(app.UIFigure);
            app.EnergyDropDown.Items = {'50', '75', '100'};
            app.EnergyDropDown.Value = '50';
            app.DistancebetweenXRaySourceandPhantomLabel = uilabel(app.UIFigure);
            app.DistancebetweenXRaySourceandPhantomLabel.Text = {'Distance between '; 'XRay Source and Phantom '; '(Source-to-Image (SID))'};
            app.DistancebetweenXRaySourceandPhantomSourcetoImageSIDDropDown = uidropdown(app.UIFigure);
            app.DistancebetweenXRaySourceandPhantomSourcetoImageSIDDropDown.Items = {'30', '65', '85'};
            app.DistancebetweenXRaySourceandPhantomSourcetoImageSIDDropDown.Value = '30';
            app.AngleindegreesLabel = uilabel(app.UIFigure);
            app.AngleindegreesLabel.Text = {'Angle'; ' in degrees'};
            app.AngleindegreesDropDown = uidropdown(app.UIFigure);
            app.AngleindegreesDropDown.Items = {'0', '15', '45', '60', '90'};
            app.AngleindegreesDropDown.Value = '15';
            app.Generate1DProfileFrom2DphantomButton = uibutton(app.UIFigure, 'push');
            app.Generate1DProfileFrom2DphantomButton.ButtonPushedFcn = createCallbackFcn(app, @Generate1DProfileFrom2DphantomButtonPushed, true);
            app.Generate1DProfileFrom2DphantomButton.Text = {'Generate 1D Profile '; 'From 2D phantom'};
            app.Generate2DImagefor3DPhantomButton = uibutton(app.UIFigure, 'push');
            app.Generate2DImagefor3DPhantomButton.ButtonPushedFcn = createCallbackFcn(app, @Generate2DImagefor3DPhantomButtonPushed, true);
            app.Generate2DImagefor3DPhantomButton.Text = {'Generate 2D Image '; 'for 3D Phantom'};
            app.DistancebetweenPhantomandFilmObjecttoImageOIDDropDownLabel = uilabel(app.UIFigure);
            app.DistancebetweenPhantomandFilmObjecttoImageOIDDropDownLabel.Text = {'Distance between '; 'Phantom and Film'; '(Object-to-Image (OID))'};
            app.DistancebetweenPhantomandFilmObjecttoImageOIDDropDown = uidropdown(app.UIFigure);
            app.DistancebetweenPhantomandFilmObjecttoImageOIDDropDown.Items = {'0.1', '2', '5'};
            app.DistancebetweenPhantomandFilmObjecttoImageOIDDropDown.Value = '0.1';
            app.PhantomthicknessDropDownLabel = uilabel(app.UIFigure);
            app.PhantomthicknessDropDownLabel.Text = 'Phantom thickness ';
            app.PhantomthicknessDropDown = uidropdown(app.UIFigure);
            app.PhantomthicknessDropDown.Items = {'2', '4', '5', '10'};
            app.PhantomthicknessDropDown.Value = '2';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MammographySimulatorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end